classdef costPerCourse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CostPerClassUIFigure            matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        CalculatetheCostPerClassatCSULabel  matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        NumberofCreditsEditFieldLabel   matlab.ui.control.Label
        NumberofCreditsEditField        matlab.ui.control.NumericEditField
        ColoradoResidentCheckBox        matlab.ui.control.CheckBox
        NumberofClassesEditFieldLabel   matlab.ui.control.Label
        NumberofClassesEditField        matlab.ui.control.NumericEditField
        CourseMeetingsperWeekEditFieldLabel  matlab.ui.control.Label
        CourseMeetingsperWeekEditField  matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        CostPerClassEditFieldLabel      matlab.ui.control.Label
        CostPerClassEditField           matlab.ui.control.NumericEditField
        CalculatePanel                  matlab.ui.container.Panel
        TuitionEditFieldLabel           matlab.ui.control.Label
        TuitionEditField                matlab.ui.control.NumericEditField
        AnotherDrBProgramLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        data
    end
    
    methods (Access = private)
        
        function updateValues(app)
            
            format bank
            
            % Assume 15 weeks per semester
            weeks = 15;
            
            % Get values from options
            resident = app.ColoradoResidentCheckBox.Value;
            numCredits = app.NumberofCreditsEditField.Value;
            numClasses = app.NumberofClassesEditField.Value;
            courseMeetings = app.CourseMeetingsperWeekEditField.Value;
            
            % Based off of credits calculate tuition
            if resident == 1
                tuition = app.data.Cost(numCredits);
            else
                tuition = app.data.NonResCost(numCredits);
            end
            
            % Update tuition field in app
            app.TuitionEditField.Value = tuition;
            
            % Calculate the cost per class for the class of interest
            costPerClass = tuition / weeks / numClasses / courseMeetings;
            
            app.CostPerClassEditField.Value = costPerClass;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % File csutution.mat containing a table of tuition values must be present
            % Table should have properties "Credits", "Cost", "NonResCost"
            % Row numbers should coorespond to credits so unnecessary but whatever
            filename = "csutuition.mat";
            format bank
            if ~isequal(exist(filename,'file'),2)
                errordlg('csutuition.mat must be in directory','Program Error');
            else
                load('csutuition.mat')
                app.data = csutution;
            end
            updateValues(app);
        end

        % Value changed function: ColoradoResidentCheckBox
        function ColoradoResidentCheckBoxValueChanged(app, event)
            updateValues(app);    
        end

        % Value changed function: NumberofCreditsEditField
        function NumberofCreditsEditFieldValueChanged(app, event)
            updateValues(app);
        end

        % Value changed function: NumberofClassesEditField
        function NumberofClassesEditFieldValueChanged(app, event)
            updateValues(app);
        end

        % Callback function
        function CourseMeetingsDropDownValueChanged(app, event)
            updateValues(app);
        end

        % Value changed function: TuitionEditField
        function TuitionEditFieldValueChanged(app, event)
            updateValues(app);
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            updateValues(app);
        end

        % Value changed function: CourseMeetingsperWeekEditField
        function CourseMeetingsperWeekEditFieldValueChanged(app, event)
            updateValues(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'Program created by: Samuel Bechara, PhD';'Completed on: 22 Jan 2019'},'About');
        end

        % Callback function
        function HelpMenuSelected(app, event)
            msgbox({'Check https://github.com/sbechara for help'});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CostPerClassUIFigure = uifigure('Visible', 'off');
            app.CostPerClassUIFigure.Name = 'CostPerClass';
            app.Menu = uimenu(app.CostPerClassUIFigure);
            app.Menu.Text = 'Menu';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.CalculatetheCostPerClassatCSULabel = uilabel(app.CostPerClassUIFigure);
            app.CalculatetheCostPerClassatCSULabel.Text = 'Calculate the Cost Per Class at CSU';
            app.OptionsPanel = uipanel(app.CostPerClassUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.NumberofCreditsEditFieldLabel = uilabel(app.OptionsPanel);
            app.NumberofCreditsEditFieldLabel.Text = 'Number of Credits';
            app.NumberofCreditsEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.NumberofCreditsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofCreditsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofCreditsEditFieldValueChanged, true);
            app.NumberofCreditsEditField.Value = 15;
            app.ColoradoResidentCheckBox = uicheckbox(app.OptionsPanel);
            app.ColoradoResidentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColoradoResidentCheckBoxValueChanged, true);
            app.ColoradoResidentCheckBox.Text = 'Colorado Resident';
            app.ColoradoResidentCheckBox.Value = true;
            app.NumberofClassesEditFieldLabel = uilabel(app.OptionsPanel);
            app.NumberofClassesEditFieldLabel.Text = 'Number of Classes';
            app.NumberofClassesEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.NumberofClassesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofClassesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofClassesEditFieldValueChanged, true);
            app.NumberofClassesEditField.Value = 4;
            app.CourseMeetingsperWeekEditFieldLabel = uilabel(app.OptionsPanel);
            app.CourseMeetingsperWeekEditFieldLabel.Text = 'Course Meetings per Week';
            app.CourseMeetingsperWeekEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CourseMeetingsperWeekEditField.ValueChangedFcn = createCallbackFcn(app, @CourseMeetingsperWeekEditFieldValueChanged, true);
            app.CourseMeetingsperWeekEditField.Value = 2;
            app.ResultsPanel = uipanel(app.CostPerClassUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.CostPerClassEditFieldLabel = uilabel(app.ResultsPanel);
            app.CostPerClassEditFieldLabel.Text = 'Cost Per Class';
            app.CostPerClassEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.CostPerClassEditField.ValueDisplayFormat = '%.2f';
            app.CostPerClassEditField.Editable = 'off';
            app.CalculatePanel = uipanel(app.CostPerClassUIFigure);
            app.CalculatePanel.Title = 'Calculate';
            app.TuitionEditFieldLabel = uilabel(app.CalculatePanel);
            app.TuitionEditFieldLabel.Text = 'Tuition';
            app.TuitionEditField = uieditfield(app.CalculatePanel, 'numeric');
            app.TuitionEditField.ValueDisplayFormat = '%.2f';
            app.TuitionEditField.ValueChangedFcn = createCallbackFcn(app, @TuitionEditFieldValueChanged, true);
            app.AnotherDrBProgramLabel = uilabel(app.CostPerClassUIFigure);
            app.AnotherDrBProgramLabel.Text = 'Another Dr.B Program';
            % Show the figure after all components are created
            app.CostPerClassUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = costPerCourse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CostPerClassUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CostPerClassUIFigure)
        end
    end
end